classdef GUI_Arena_20230123_05 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StimuliUIFigure                matlab.ui.Figure
        confconf_stripesstripes_num2strveltomlLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        WidefieldmotionTab             matlab.ui.container.Tab
        testwidefieldmotionPanel       matlab.ui.container.Panel
        selectpngfileButton            matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        createfilesButton              matlab.ui.control.RadioButton
        putfilesButton                 matlab.ui.control.RadioButton
        executeButton                  matlab.ui.control.RadioButton
        selecttomlfileButton           matlab.ui.control.Button
        clockratemhzEditField          matlab.ui.control.EditField
        clockratemhzEditFieldLabel     matlab.ui.control.Label
        stripecolourRGBEditField       matlab.ui.control.EditField
        stripecolourRGBEditFieldLabel  matlab.ui.control.Label
        stimulusdurationsEditFieldLabel  matlab.ui.control.Label
        stimulusdurationsEditField     matlab.ui.control.NumericEditField
        FicTracCheckBox                matlab.ui.control.CheckBox
        IPEditFieldLabel               matlab.ui.control.Label
        IPEditField                    matlab.ui.control.EditField
        portEditFieldLabel             matlab.ui.control.Label
        portEditField                  matlab.ui.control.EditField
        StartButton                    matlab.ui.control.Button
        directionButtonGroup           matlab.ui.container.ButtonGroup
        CWBWButton                     matlab.ui.control.RadioButton
        CCWFWButton                    matlab.ui.control.RadioButton
        bothButton_2                   matlab.ui.control.RadioButton
        orderButtonGroup               matlab.ui.container.ButtonGroup
        randomButton                   matlab.ui.control.RadioButton
        sequenceButton                 matlab.ui.control.RadioButton
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        rotationalButton               matlab.ui.control.RadioButton
        translationalButton            matlab.ui.control.RadioButton
        bothButton                     matlab.ui.control.RadioButton
        movementtypeLabel_2            matlab.ui.control.Label
        pausesEditFieldLabel           matlab.ui.control.Label
        pausesEditField                matlab.ui.control.NumericEditField
        spatfreqrowsEditField          matlab.ui.control.EditField
        spatfreqrowsEditFieldLabel     matlab.ui.control.Label
        tempfreqHzEditField            matlab.ui.control.EditField
        tempfreqHzEditFieldLabel       matlab.ui.control.Label
        IParenaEditField               matlab.ui.control.EditField
        IParenaEditFieldLabel          matlab.ui.control.Label
        SaveCheckBox                   matlab.ui.control.CheckBox
        StimulusoutputPanel            matlab.ui.container.Panel
        CountPanel                     matlab.ui.container.Panel
        TotalEditFieldLabel            matlab.ui.control.Label
        TotalEditField                 matlab.ui.control.NumericEditField
        CurrentEditFieldLabel          matlab.ui.control.Label
        CurrentEditField               matlab.ui.control.NumericEditField
        HistoryPanel                   matlab.ui.container.Panel
        EditField                      matlab.ui.control.EditField
        EditField_2                    matlab.ui.control.EditField
        EditField_3                    matlab.ui.control.EditField
        movementtypeLabel              matlab.ui.control.Label
        spatfreqrowsLabel              matlab.ui.control.Label
        tempfreqHzLabel                matlab.ui.control.Label
        ContinuousTab                  matlab.ui.container.Tab
        testwidefieldmotionPanel_2     matlab.ui.container.Panel
        clockratemhzEditField_2        matlab.ui.control.EditField
        clockratemhzEditField_2Label   matlab.ui.control.Label
        stripecolourRGBEditField_3     matlab.ui.control.EditField
        stripecolourRGBEditField_3Label  matlab.ui.control.Label
        stimulusdurationsEditField_2Label  matlab.ui.control.Label
        stimulusdurationsEditField_2   matlab.ui.control.NumericEditField
        StopButton                     matlab.ui.control.Button
        pausesEditField_4Label         matlab.ui.control.Label
        pausesEditField_4              matlab.ui.control.NumericEditField
        spatfreqrowsEditField_3        matlab.ui.control.EditField
        spatfreqrowsEditField_3Label   matlab.ui.control.Label
        tempfreqHzEditField_3          matlab.ui.control.EditField
        tempfreqHzEditField_3Label     matlab.ui.control.Label
        IParenaEditField_3             matlab.ui.control.EditField
        IParenaEditField_3Label        matlab.ui.control.Label
        SaveCheckBox_3                 matlab.ui.control.CheckBox
        StartButton_3                  matlab.ui.control.Button
        movementtypeButtonGroup        matlab.ui.container.ButtonGroup
        translationalButton_3          matlab.ui.control.RadioButton
        rotationalButton_3             matlab.ui.control.RadioButton
        bothButton_6                   matlab.ui.control.RadioButton
        directionButtonGroup_2         matlab.ui.container.ButtonGroup
        CWBWButton_2                   matlab.ui.control.RadioButton
        CCWFWButton_2                  matlab.ui.control.RadioButton
        bothButton_4                   matlab.ui.control.RadioButton
        StimulusoutputPanel_3          matlab.ui.container.Panel
        CountPanel_3                   matlab.ui.container.Panel
        TotalEditField_3Label          matlab.ui.control.Label
        TotalEditField_3               matlab.ui.control.NumericEditField
        CurrentEditField_3Label        matlab.ui.control.Label
        CurrentEditField_3             matlab.ui.control.NumericEditField
        HistoryPanel_3                 matlab.ui.container.Panel
        EditField_9                    matlab.ui.control.EditField
        EditField_10                   matlab.ui.control.EditField
        EditField_11                   matlab.ui.control.EditField
        movementtypeLabel_5            matlab.ui.control.Label
        spatfreqrowsLabel_3            matlab.ui.control.Label
        tempfreqHzLabel_3              matlab.ui.control.Label
        ReceptivefieldsTab             matlab.ui.container.Tab
        testreceptivefieldsPanel       matlab.ui.container.Panel
        StartButton_2                  matlab.ui.control.Button
        Panel_5                        matlab.ui.container.Panel
        singlestripeCheckBox           matlab.ui.control.CheckBox
        ButtonGroup_3                  matlab.ui.container.ButtonGroup
        CWButton                       matlab.ui.control.RadioButton
        CCWButton                      matlab.ui.control.RadioButton
        bothButton_3                   matlab.ui.control.RadioButton
        ButtonGroup_4                  matlab.ui.container.ButtonGroup
        randomButton_2                 matlab.ui.control.RadioButton
        sequenceButton_2               matlab.ui.control.RadioButton
        repetitionsEditFieldLabel      matlab.ui.control.Label
        repetitionsEditField           matlab.ui.control.NumericEditField
        tempfreqHzEditField_2Label     matlab.ui.control.Label
        tempfreqHzEditField_2          matlab.ui.control.EditField
        spatfreqrowsEditField_2Label   matlab.ui.control.Label
        spatfreqrowsEditField_2        matlab.ui.control.EditField
        pausesEditField_2Label         matlab.ui.control.Label
        pausesEditField_2              matlab.ui.control.NumericEditField
        Panel_6                        matlab.ui.container.Panel
        spiketriggeredaverageCheckBox  matlab.ui.control.CheckBox
        imagesEditFieldLabel           matlab.ui.control.Label
        imagesEditField                matlab.ui.control.EditField
        widthxpxEditFieldLabel         matlab.ui.control.Label
        widthxpxEditField              matlab.ui.control.EditField
        widthypxEditFieldLabel         matlab.ui.control.Label
        widthypxEditField              matlab.ui.control.EditField
        squaresEditFieldLabel          matlab.ui.control.Label
        squaresEditField               matlab.ui.control.EditField
        sizesquarespxLabel             matlab.ui.control.Label
        sizesquarespxEditField         matlab.ui.control.EditField
        Button_2                       matlab.ui.control.StateButton
        durationstimsEditFieldLabel    matlab.ui.control.Label
        durationstimsEditField         matlab.ui.control.EditField
        pausesEditField_3Label         matlab.ui.control.Label
        pausesEditField_3              matlab.ui.control.EditField
        EditField_7                    matlab.ui.control.EditField
        Button                         matlab.ui.control.StateButton
        EditField_8                    matlab.ui.control.EditField
        IParenaEditField_2             matlab.ui.control.EditField
        IParenaEditField_2Label        matlab.ui.control.Label
        stripecolourRGBEditField_2Label  matlab.ui.control.Label
        stripecolourRGBEditField_2     matlab.ui.control.EditField
        SaveCheckBox_2                 matlab.ui.control.CheckBox
        StimulusoutputPanel_2          matlab.ui.container.Panel
        ImageAxes                      matlab.ui.control.UIAxes
        RedAxes                        matlab.ui.control.UIAxes
        GreenAxes                      matlab.ui.control.UIAxes
        BlueAxes                       matlab.ui.control.UIAxes
        CountPanel_2                   matlab.ui.container.Panel
        TotalEditField_2Label          matlab.ui.control.Label
        TotalEditField_2               matlab.ui.control.NumericEditField
        CurrentEditField_2Label        matlab.ui.control.Label
        CurrentEditField_2             matlab.ui.control.NumericEditField
        HistoryPanel_2                 matlab.ui.container.Panel
        EditField_4                    matlab.ui.control.EditField
        EditField_5                    matlab.ui.control.EditField
        EditField_6                    matlab.ui.control.EditField
        movementtypeLabel_3            matlab.ui.control.Label
        spatfreqrowsLabel_2            matlab.ui.control.Label
        tempfreqHzLabel_2              matlab.ui.control.Label
        POLTab                         matlab.ui.container.Tab
        Panel                          matlab.ui.container.Panel
        commandEditField_2Label        matlab.ui.control.Label
        commandEditField_2             matlab.ui.control.EditField
        GoButton                       matlab.ui.control.Button
    end

    
    methods (Access = private)
        
         function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file 
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.RedAxes, im, 'FaceColor',[1 0 0],'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im, 'FaceColor',[0 1 0],'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im, 'FaceColor',[0 0 1],'EdgeColor', 'none');
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                    % Plot the histograms
                    histr = histogram(app.RedAxes, im(:,:,1), 'FaceColor', [1 0 0], 'EdgeColor', 'none');
                    histg = histogram(app.GreenAxes, im(:,:,2), 'FaceColor', [0 1 0], 'EdgeColor', 'none');
                    histb = histogram(app.BlueAxes, im(:,:,3), 'FaceColor', [0 0 1], 'EdgeColor', 'none');
                    
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end
                % Get largest bin count
                maxr = max(histr.BinCounts);
                maxg = max(histg.BinCounts);
                maxb = max(histb.BinCounts);
                maxcount = max([maxr maxg maxb]);
                
                % Set y axes limits based on largest bin count
                app.RedAxes.YLim = [0 maxcount];
                app.RedAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.GreenAxes.YLim = [0 maxcount];
                app.GreenAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
                app.BlueAxes.YLim = [0 maxcount];
                app.BlueAxes.YTick = round([0 maxcount/2 maxcount], 2, 'significant');
         
         end
        
        
         function rec = realtimefunction(app,velorder,ty,stripewidth,mypi)
             rec = [];
             while true 
                tic
%                 drawnow() % chance to interrupt/stop
                pause(0.001)
                toc
                tic
                need_to_stop = get(app.StartButton_3, 'UserData');
                if ~isempty(need_to_stop) && need_to_stop
                    break
                end
                
                disp('works')
                 
                toc
                for i = 1 : size(velorder,2)
                    app.CurrentEditField_3.Value = i;

                    % write stimuli -> execute
                    if ty(i) == 1 % rotation
                        typeval = 'rotational';
                    elseif ty(i) == 2 % translation
                        typeval = 'translational';
                    end

                    %                         app.EditField_9.Value = stim.type;
                    %                         app.EditField_10.Value = stim.width;
                    %                         app.EditField_11.Value = stim.vel;
                    stim = control_pi(velorder(i), typeval, mypi, stripewidth(i));
                    rectemp(i) = stim; %#ok<AGROW>
                    pause((app.pausesEditField_4.Value + app.stimulusdurationsEditField_2.Value)) % this is in seconds

                end
                rec = [rec rectemp];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: singlestripeCheckBox
        function singlestripeCheckBoxValueChanged(app, event)
            if app.singlestripeCheckBox.Value == 1 % checked
                app.tempfreqHzEditField_2.Enable = 'on';
                app.spatfreqrowsEditField_2.Enable = 'on';
                app.tempfreqHzEditField_2Label.Enable = 'on';
                app.CCWButton.Enable = 'on';
                app.CWButton.Enable = 'on';
                app.bothButton_3.Enable = 'on';
                app.pausesEditField_2.Enable = 'on';
                app.repetitionsEditField.Enable = 'on';
                app.randomButton_2.Enable = 'on';
                app.sequenceButton_2.Enable = 'on';
                app.pausesEditField_2Label.Enable = 'on';
                app.repetitionsEditFieldLabel.Enable = 'on';
                app.HistoryPanel_2.Visible = 'on';
                app.CountPanel_2.Visible = 'on';
            elseif app.singlestripeCheckBox.Value == 0 % unchecked
                app.tempfreqHzEditField_2.Enable = 'off';
                app.spatfreqrowsEditField_2.Enable = 'off';
                app.tempfreqHzEditField_2Label.Enable = 'off';
                app.CCWButton.Enable = 'off';
                app.CWButton.Enable = 'off';
                app.bothButton_3.Enable = 'off';
                app.pausesEditField_2.Enable = 'off';
                app.repetitionsEditField.Enable = 'off';
                app.randomButton_2.Enable = 'off';
                app.sequenceButton_2.Enable = 'off';
                app.pausesEditField_2Label.Enable = 'off';
                app.repetitionsEditFieldLabel.Enable = 'off';
                app.HistoryPanel_2.Visible = 'off';
                app.CountPanel_2.Visible = 'off';
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            % get type of stripe movement
            disp(selectedButton)
            switch get(get(app.ButtonGroup,'SelectedObject'),'Text')
                case get(app.executeButton,'Text')

                    % delete default values
                    app.clockratemhzEditField.Value = '';
                    app.stripecolourRGBEditField.Value = '';
                    app.stimulusdurationsEditField.Visible = 'off';
                    % disable fields
                    app.clockratemhzEditFieldLabel.Enable = 'off';
                    app.clockratemhzEditField.Enable = 'off';
                    app.stripecolourRGBEditFieldLabel.Enable = 'off';
                    app.stripecolourRGBEditField.Enable = 'off';
                    app.selectpngfileButton.Enable = 'off';
                    app.selecttomlfileButton.Enable = 'off';
                    app.FicTracCheckBox.Enable = 'off';
                    app.stimulusdurationsEditField.Enable = 'off';
                    app.stimulusdurationsEditFieldLabel.Enable = 'off';
                case get(app.putfilesButton,'Text')

                    % enable fields
                    app.selectpngfileButton.Enable = 'on';
                    app.selecttomlfileButton.Enable = 'on';

                    % delete default values
                    app.clockratemhzEditField.Value = '';
                    %                     app.spatfreqrowsEditField.Value = '';
                    app.stripecolourRGBEditField.Value = '';
                    app.stimulusdurationsEditField.Visible = 'off';
                    % disable fields
                    app.clockratemhzEditFieldLabel.Enable = 'off';
                    app.clockratemhzEditField.Enable = 'off';
                    %                     app.spatfreqrowsEditFieldLabel.Enable = 'off';
                    %                     app.spatfreqrowsEditField.Enable = 'off';
                    app.stripecolourRGBEditFieldLabel.Enable = 'off';
                    app.stripecolourRGBEditField.Enable = 'off';
                    app.FicTracCheckBox.Enable = 'off';
                    app.stimulusdurationsEditField.Enable = 'off';
                    app.stimulusdurationsEditFieldLabel.Enable = 'off';
                case get(app.createfilesButton,'Text')

                    % set default values
                    app.clockratemhzEditField.Value = '8';
                    app.stripecolourRGBEditField.Value = '0, 255, 0';
                    app.stimulusdurationsEditField.Visible = 'on';
                    app.stimulusdurationsEditField.Value = 5;

                    % enable fields
                    app.clockratemhzEditFieldLabel.Enable = 'on';
                    app.clockratemhzEditField.Enable = 'on';
                    app.stripecolourRGBEditFieldLabel.Enable = 'on';
                    app.stripecolourRGBEditField.Enable = 'on';
                    app.FicTracCheckBox.Enable = 'on';
                    app.stimulusdurationsEditField.Enable = 'on';
                    app.stimulusdurationsEditFieldLabel.Enable = 'on';


                    % disable fields
                    app.selectpngfileButton.Enable = 'off';
                    app.selecttomlfileButton.Enable = 'off';

            end
        end

        % Button pushed function: selecttomlfileButton
        function selecttomlfileButtonPushed(app, event)

            % open ui to get file(s)
            [file,path] = uigetfile({'*.toml'},'MultiSelect',"on");
            disp(file{1})
            disp(path)

        end

        % Button pushed function: selectpngfileButton
        function selectpngfileButtonPushed(app, event)

            % open ui to get file(s)
            [file,path] = uigetfile({'*.png'},'MultiSelect',"on");
            disp(file)
            disp(path)

        end

        % Value changed function: FicTracCheckBox
        function FicTracCheckBoxValueChanged(app, event)
            %             value = app.FicTracCheckBox.Value;

            if app.FicTracCheckBox.Value == 1 % checkbox is checked

                % enable fields
                app.IPEditField.Enable = 'on';
                app.IPEditFieldLabel.Enable = 'on';
                app.portEditField.Enable = 'on';
                app.portEditFieldLabel.Enable = 'on';

            elseif app.FicTracCheckBox.Value == 0 % checkbox is not checked

                % disable fields
                app.IPEditField.Enable = 'off';
                app.IPEditFieldLabel.Enable = 'off';
                app.portEditField.Enable = 'off';
                app.portEditFieldLabel.Enable = 'off';

            end
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            % get movement velocities
            velPol = str2num(app.commandEditField_2.Value); %#ok<ST2NM>
            %             size(velPol,2)
            s = serial('COM4','Baudrate',115200); %#ok<SERIAL> %set port and bitrate
            fopen(s);
            fscanf(s);

            %%%%%%%%%%%%%%%%%%%%%%%%%%% send commands %%%%%%%%%%%%%%%%%%%%%%%%%%%

            %             fprintf(s,"4");     % testLED
            %             fprintf(s,"600");   % rotation

            for i = 1 : size(velPol,2)
                fprintf(s,"20");   % change POLled mode = ON
                fprintf(s,num2str(velPol(i)));
                pause(30)
                fprintf(s,"21");   % change POLled mode = OFF
                recPol = num2str(velPol);
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % close arduino
            fclose(s);

            % save POL velocities
            %             save filePOL.mat recPol
            if app.SaveCheckBox.Value == 1
                filename = sprintf('%s_POL.mat', datestr(now,'yyyymmdd_HHMMSS'));
                save(filename,'recPol')
            end


            % create new button to start POL and Arena. Need to insert
            % delay. If I got it right pause. Can I wait till the end of a
            % function
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%%%%% extract variables from ui %%%%%%%%%%%%%%%%%%%%%%%%
            % get type of stripe movement
            switch get(get(app.ButtonGroup_2,'SelectedObject'),'Text')
                case get(app.translationalButton,'Text'),  type = 'translational';
                case get(app.rotationalButton,'Text'),  type = 'rotational';
                case get(app.bothButton,'Text'),  type = 'both';
            end

            % get direction of stripe movement
            switch get(get(app.directionButtonGroup,'SelectedObject'),'Text')
                case get(app.CCWFWButton,'Text'),  direction = 'CCWFW';
                case get(app.CWBWButton,'Text'),  direction = 'CWBW';
                case get(app.bothButton_2, 'Text'), direction = 'both';
            end

            % get order of stimuli
            switch get(get(app.orderButtonGroup,'SelectedObject'),'Text')
                case get(app.sequenceButton,'Text'),  order = 'sequence';
                case get(app.randomButton,'Text'),  order = 'random';
            end

            % get movement velocities
            vel = str2num(app.tempfreqHzEditField.Value); %#ok<ST2NM>

            % get IP
            IP = app.IParenaEditField.Value;

            % get stimulus duration
            duration = app.stimulusdurationsEditField.Value*100*2;

            % get stripe width
            width = str2num(app.spatfreqrowsEditField.Value); %#ok<ST2NM>


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% execute stimuli %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % connect to pi
            mypi = connect_pi(IP);

            % prepare vars to execute toml files
            switch type
                case 'rotational'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)) = 1; % 1 = ccw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'CWBW'
                            dir(1:size(vel,2)) = 2; % 2 = cw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:size(vel,2)) = 1;
                            dir(size(vel,2)+1:size(vel,2)*2) = 2;
                            c = size(vel,2)*2;
                            velcount = 2;
                    end
                    ty(1:c) = 1; % 1 = rotation

                case 'translational'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)) = 1; % 1 = ccw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'CWBW'
                            dir(1:size(vel,2)) = 2; % 2 = cw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:size(vel,2)) = 1;
                            dir(size(vel,2)+1:size(vel,2)*2) = 2;
                            c = size(vel,2)*2;
                            velcount = 2;
                    end
                    ty(1:c) = 2; % 2 = translation

                case 'both'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)*2) = 1; % 1 = ccw dir
                            ty(1:2:size(vel,2)*2) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*2) = 2; % translation
                            velcount = 2;
                        case 'CWBW'
                            dir (1:size(vel,2)*2) = 2; % 2 = cw dir
                            ty(1:2:size(vel,2)*2) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*2) = 2; % translation
                            velcount = 2;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:4:size(vel,2)*4) = 1;
                            dir(2:4:size(vel,2)*4) = 1;
                            dir(3:4:size(vel,2)*4) = 2;
                            dir(4:4:size(vel,2)*4) = 2;
                            ty(1:2:size(vel,2)*4) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*4) = 2; % translation
                            velcount = 4;
                    end
            end

            switch velcount
                case 1
                    velorder = vel;
                case 2
                    velorder = [vel vel];
                case 4
                    velorder = [vel vel vel vel];
            end

            velorder = sort(velorder);
            veltemp = []; dirtemp = []; tytemp = [];
            t = 1;
            for s = 1 : length(width)
                veltemp = [veltemp velorder]; %#ok<AGROW>
                dirtemp = [dirtemp dir]; %#ok<AGROW>
                tytemp = [tytemp ty]; %#ok<AGROW>
                stripewidth(t:t+length(velorder)-1) = width(s);
                t = t + length(velorder);
            end

            velorder = veltemp;
            dir = dirtemp;
            ty = tytemp;

            % set order of stimuli, either in a sequence or randomized
            switch order
                case 'sequence'
                    stimorder = 1:size(velorder,2); %#ok<NASGU> % sequence
                case 'random'

                    % randomize stim order
                    stimorder = randperm(size(velorder,2),size(velorder,2));

                    % rearrange stimuli order
                    dir = dir(stimorder);
                    ty = ty(stimorder);
                    velorder = velorder(stimorder);
                    stripewidth = stripewidth(stimorder);

            end

            for c = 1 : size(dir,2)
                if dir(c) == 1 % CCW FW
                elseif dir(c) == 2 % CWBW
                    velorder(c) = velorder(c)*(-1);
                end
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%% create sequencer to pi %%%%%%%%%%%%%%%%%%%%%%%%%%
            % create sequencer files if wished and put them on pi
            switch get(get(app.ButtonGroup,'SelectedObject'),'Text')
                case get(app.executeButton,'Text')
                case get(app.putfilesButton,'Text')
                    % user selects files which are uplouaded on pi
                case get(app.createfilesButton,'Text')

                    % get colour values for stripe pattern
                    col = str2num(app.stripecolourRGBEditField.Value); %#ok<ST2NM>

                    % create image
                    for g = 1 : length(width)
                        im.(['img',num2str(g)]) = create_image(width(g), col, mypi);
                    end

                    % create toml file (need to adjust frequency and directin of movement)
                    for i = 1 : size(ty,2) % not sure whether size 1 or 2
                        h = find(stripewidth(i) == width);
                        display(stripewidth(i))
                        switch type
                            case 'rotational'
                                seq_files(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i));    % arena
                            case 'translational'
                                seq_files_split(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i)) % arena-split
                            case 'both'
                                if ty(i) == 1 % rotation - arena
                                    seq_files(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i));    % arena
                                elseif ty(i) == 2 % translation - arena-split
                                    seq_files_split(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i)) % arena-split
                                end

                        end
                    end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% set ouput %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.TotalEditField.Value = size(velorder,2);

            %%%%%%%%%%%%%%%%%%%%%%%%% execute stimuli %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % write and execute stimuli
            size(velorder,2)
            for i = 1 : size(velorder,2)
                app.CurrentEditField.Value = i;
                
                % write stimuli -> execute
                if ty(i) == 1 % rotation
                    typeval = 'rotational';
                elseif ty(i) == 2 % translation
                    typeval = 'translational';
                end

                app.EditField.Value = stim.type;
                app.EditField_2.Value = stim.width;
                app.EditField_3.Value = stim.vel;
                stim = control_pi(velorder(i), typeval, mypi, stripewidth(i));
                rec(i) = stim; %#ok<AGROW>

                pause((app.stimulusdurationsEditField.Value + app.pausesEditField.Value)) % this is in seconds
            end

            disp('done')
            if app.SaveCheckBox.Value == 1
                filename = sprintf('%s_Arena.mat', datestr(now,'yyyymmdd_HHMMSS'));
                save(filename,'rec')
            end


            %%%%%%%%%%%%%%%%%%%%%%%%% clear pi %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            clear mypi

        end

        % Button pushed function: StartButton_2
        function StartButton_2Pushed(app, event)
            
            % check for stimuli type to present
            if app.singlestripeCheckBox.Value == 1 % checked
                % extract variables from UI
                % get direction of stripe movement
                switch get(get(app.ButtonGroup_3,'SelectedObject'),'Text')
                    case get(app.CCWButton,'Text'),  direction = 'CCW';
                    case get(app.CWButton,'Text'),  direction = 'CW';
                    case get(app.bothButton_3, 'Text'), direction = 'both';
                end
                % get order of stimuli
                switch get(get(app.ButtonGroup_4,'SelectedObject'),'Text')
                    case get(app.sequenceButton_2,'Text'),  order = 'sequence';
                    case get(app.randomButton_2,'Text'),  order = 'random';
                end
                % get movement velocities
                vel = str2num(app.tempfreqHzEditField_2.Value); %#ok<ST2NM>
                % get stripe width
                width = str2num(app.spatfreqrowsEditField_2.Value); %#ok<ST2NM>
                % get # of repetitions per test stimulus
                rep = app.repetitionsEditField.Value;
                
                % prepare vars to execute toml files
                switch direction
                    case 'CCW'
                        dir(1:size(vel,2)) = 1; % 1 = ccw dir
                        c = size(vel,2); velcount = 1;
                    case 'CW'
                        dir(1:size(vel,2)) = 2; % 2 = cw dir
                        c = size(vel,2); velcount = 1;
                    case 'both'
                        dir(1:2:2*size(vel,2)) = 1;
                        dir(2:2:2*size(vel,2)) = 2;
                        c = size(vel,2)*2; velcount = 2;
                end
                ty(1:c) = 1; % 1 = rotation
                switch velcount
                    case 1
                        velorder = vel;
                    case 2
                        velorder = [vel vel];
                end
                velorder = sort(velorder);
                veltemp = []; dirtemp = []; tytemp = [];
                t = 1;
                for s = 1 : length(width)
                    veltemp = [veltemp velorder]; %#ok<AGROW>
                    dirtemp = [dirtemp dir]; %#ok<AGROW>
                    tytemp = [tytemp ty]; %#ok<AGROW>
                    stripewidth(t:t+length(velorder)-1) = width(s);
                    t = t + length(velorder);
                end
                velorder = veltemp;
                dir = dirtemp;
                ty = tytemp;
                % set order of stimuli, either in a sequence or randomized
                switch order
                    case 'sequence'
                        stimorder = 1:size(velorder,2); %#ok<NASGU> % sequence
                    case 'random'
                        % randomize stim order
                        stimorder = randperm(size(velorder,2),size(velorder,2));
                        % rearrange stimuli order
                        dir = dir(stimorder);
                        ty = ty(stimorder);
                        velorder = velorder(stimorder);
                        stripewidth = stripewidth(stimorder);
                end
                for c = 1 : size(dir,2)
                    if dir(c) == 1 % CCW FW
                    elseif dir(c) == 2 % CWBW
                        velorder(c) = velorder(c)*(-1);
                    end
                end
            elseif app.spiketriggeredaverageCheckBox.Value == 1
                % get number of images
                numIm = str2num(app.imagesEditField.Value); %#ok<ST2NM>
                % get square information
                numSq = str2num(app.squaresEditField.Value); %#ok<ST2NM>
                sizeSq = str2num(app.sizesquarespxEditField.Value); %#ok<ST2NM>
                % get width of scrren
                wX = str2num(app.widthxpxEditField.Value)/sizeSq; %#ok<ST2NM>
                wY = str2num(app.widthypxEditField.Value)/sizeSq; %#ok<ST2NM>
                % get stimulus information
                stimDuration = str2num(app.durationstimsEditField.Value); %#ok<ST2NM> 
                pauseDuration = str2num(app.pausesEditField_3.Value); %#ok<ST2NM> 
            elseif app.singlestripeCheckBox.Value == 1 && app.spiketriggeredaverageCheckBox.Value == 1
                disp('too many stimuli')
            else
                disp('choose stimuli')
            end
            
            % get IP
            IP = app.IParenaEditField.Value;
            
            % connect to pi
            mypi = connect_pi(IP);
            
            % create sequencer to pi
            col = str2num(app.stripecolourRGBEditField_2.Value); %#ok<ST2NM> 
            
            if  app.singlestripeCheckBox.Value == 1
                % create image
                for g = 1 : length(width)
                    test_im.(['test_img',num2str(g)]) = create_test_stripe_image(width(g), col, mypi);
                end
                % create toml file (need to adjust frequency and direction of movement)
                for i = 1 : size(ty,2) % not sure whether size 1 or 2
                    h = find(stripewidth(i) == width);
                    display(stripewidth(i))
                    duration(i) = test_single_stripe_seq(test_im.(['test_img',num2str(h)]), velorder(i), rep, mypi, stripewidth(i), dir(i));    %#ok<AGROW> % arena
                end
            elseif app.spiketriggeredaverageCheckBox.Value == 1
                % create images
                I = create_image_STA(numIm, numSq, sizeSq, wX, wY, col, mypi);
                % show output
                STA_Mean(I)
                app.ImageAxes.Visible = 'on';
                app.BlueAxes.Visible = 'on';
                app.GreenAxes.Visible = 'on';
                app.RedAxes.Visible = 'on';
                updateimage(app, 'SpikeTriggeredAverage/MeanImage.png');
                
                % ask if images okay 
                fig = uifigure;
                uiconfirm(fig,'Continue with image sequence?','Confirm Images','CloseFcn',@mycallback);
                
                % create toml file
                seq_files_STA(numIm, stimDuration, pauseDuration, mypi);    % % arena
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% execute stimuli %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % write and execute stimuli
            if app.singlestripeCheckBox.Value == 1
                size(velorder,2)
                app.HistoryPanel_2.Visible = 'on';
                app.CountPanel_2.Visible = 'on';
                app.TotalEditField_2.Value = size(velorder,2);
                for i = 1 : size(velorder,2)
                    % write stimuli -> execute
                    %                     typeval = 'rotational';
                    app.CurrentEditField_2.Value = i;
                    app.EditField_4.Value = stim.type;
                    app.EditField_5.Value = stim.width;
                    app.EditField_6.Value = stim.vel;
                    stim = test_control_pi(velorder(i), mypi, stripewidth(i));
                    rec(i) = stim; %#ok<AGROW>
                    
                    pause((duration(i)/1000)*app.repetitionsEditField.Value + app.pausesEditField_2.Value) % this is in seconds
                end
                disp('done')
                if app.SaveCheckBox_2.Value == 1
                    filename = sprintf('%s_test_Arena.mat', datestr(now,'yyyymmdd_HHMMSS'));
                    save(filename,'rec')
                end
            elseif app.spiketriggeredaverageCheckBox.Value == 1
                targetDirPath = 'go/bin';
                exeName = 'arena conf/STA.toml';
                command = ['cd ' targetDirPath '; ./' exeName ' &> 1 &'];
                system(mypi,command)
%                 if app.SaveCheckBox_2.Value == 0
%                     cd 'D:\setup\matlab'
%                     rmdir(folder)
%                 end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% clear pi %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            clear mypi
            
        end

        % Value changed function: spiketriggeredaverageCheckBox
        function spiketriggeredaverageCheckBoxValueChanged(app, event)
            if app.spiketriggeredaverageCheckBox.Value == 1 % checked
                app.pausesEditField_3.Enable = 'on';
                app.pausesEditField_3Label.Enable = 'on';
                app.durationstimsEditField.Enable = 'on';
                app.durationstimsEditFieldLabel.Enable = 'on';
                app.Button.Enable = 'on';
                app.Button_2.Enable = 'on';
                app.sizesquarespxEditField.Enable = 'on';
                app.sizesquarespxLabel.Enable = 'on';
                app.squaresEditField.Enable = 'on';
                app.squaresEditFieldLabel.Enable = 'on';
                app.widthxpxEditField.Enable = 'on';
                app.widthxpxEditFieldLabel.Enable = 'on';
                app.widthypxEditField.Enable = 'on';
                app.widthypxEditFieldLabel.Enable = 'on';
                app.imagesEditField.Enable = 'on';
                app.imagesEditFieldLabel.Enable = 'on';
%                 app.selectpngfileButton.Enable = 'on';
%                 app.selecttomlfileButton.Enable = 'on';
                app.EditField_7.Enable = 'on';
                app.EditField_8.Enable = 'on';
            elseif app.spiketriggeredaverageCheckBox.Value == 0 % unchecked
                app.pausesEditField_3.Enable = 'off';
                app.pausesEditField_3Label.Enable = 'off';
                app.durationstimsEditField.Enable = 'off';
                app.durationstimsEditFieldLabel.Enable = 'off';
                app.Button.Enable = 'off';
                app.Button_2.Enable = 'off';
                app.sizesquarespxEditField.Enable = 'off';
                app.sizesquarespxLabel.Enable = 'off';
                app.squaresEditField.Enable = 'off';
                app.squaresEditFieldLabel.Enable = 'off';
                app.widthxpxEditField.Enable = 'off';
                app.widthxpxEditFieldLabel.Enable = 'off';
                app.widthypxEditField.Enable = 'off';
                app.widthypxEditFieldLabel.Enable = 'off';
                app.imagesEditField.Enable = 'off';
                app.imagesEditFieldLabel.Enable = 'off';
%                 app.selectpngfileButton.Enable = 'off';
%                 app.selecttomlfileButton.Enable = 'off';
                app.EditField_7.Enable = 'off';
                app.EditField_8.Enable = 'off';
                app.ImageAxes.Visible = 'off';
                app.BlueAxes.Visible = 'off';
                app.GreenAxes.Visible = 'off';
                app.RedAxes.Visible = 'off';
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            imageFolder = uigetdir;
            app.EditField_7.Value = imageFolder;
            
        end

        % Value changed function: Button_2
        function Button_2ValueChanged(app, event)
            [FileName, FilePath] = uigetfile('*.toml');
            app.EditField_8.Value = [FilePath, FileName];
            
        end

        % Button pushed function: StartButton_3
        function StartButton_3Pushed(app, event)
            % find variables (values)
          
            % switch for direction and typer of movement
            
            % save true or false?
            
            % while loop until stop == true   
            
            
            %%%%%%%%%%%%%%%%%%%%%%%% extract variables from ui %%%%%%%%%%%%%%%%%%%%%%%%
            % get type of stripe movement
            switch get(get(app.movementtypeButtonGroup,'SelectedObject'),'Text')
                case get(app.translationalButton_3,'Text'),  type = 'translational';
                case get(app.rotationalButton_3,'Text'),  type = 'rotational';
                case get(app.bothButton_6,'Text'),  type = 'both';
            end

            % get direction of stripe movement
            switch get(get(app.directionButtonGroup,'SelectedObject'),'Text')
                case get(app.CCWFWButton_2,'Text'),  direction = 'CCWFW';
                case get(app.CWBWButton_2,'Text'),  direction = 'CWBW';
                case get(app.bothButton_4, 'Text'), direction = 'both';
            end

            % get movement velocities
            vel = str2num(app.tempfreqHzEditField_3.Value); %#ok<ST2NM>

            % get IP
            IP = app.IParenaEditField_3.Value;

            % get stimulus duration
            duration = app.stimulusdurationsEditField_2.Value*100*2;

            % get stripe width
            width = str2num(app.spatfreqrowsEditField_3.Value); %#ok<ST2NM>

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%% execute stimuli %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % connect to pi
            mypi = connect_pi(IP);

            % prepare vars to execute toml files
            switch type
                case 'rotational'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)) = 1; % 1 = ccw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'CWBW'
                            dir(1:size(vel,2)) = 2; % 2 = cw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:size(vel,2)) = 1;
                            dir(size(vel,2)+1:size(vel,2)*2) = 2;
                            c = size(vel,2)*2;
                            velcount = 2;
                    end
                    ty(1:c) = 1; % 1 = rotation

                case 'translational'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)) = 1; % 1 = ccw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'CWBW'
                            dir(1:size(vel,2)) = 2; % 2 = cw dir
                            c = size(vel,2);
                            velcount = 1;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:size(vel,2)) = 1;
                            dir(size(vel,2)+1:size(vel,2)*2) = 2;
                            c = size(vel,2)*2;
                            velcount = 2;
                    end
                    ty(1:c) = 2; % 2 = translation

                case 'both'
                    switch direction
                        case 'CCWFW'
                            dir(1:size(vel,2)*2) = 1; % 1 = ccw dir
                            ty(1:2:size(vel,2)*2) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*2) = 2; % translation
                            velcount = 2;
                        case 'CWBW'
                            dir (1:size(vel,2)*2) = 2; % 2 = cw dir
                            ty(1:2:size(vel,2)*2) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*2) = 2; % translation
                            velcount = 2;
                        case 'both' % r ccw cw t ccw cw
                            dir(1:4:size(vel,2)*4) = 1;
                            dir(2:4:size(vel,2)*4) = 1;
                            dir(3:4:size(vel,2)*4) = 2;
                            dir(4:4:size(vel,2)*4) = 2;
                            ty(1:2:size(vel,2)*4) = 1; % 1 = rotation
                            ty(2:2:size(vel,2)*4) = 2; % translation
                            velcount = 4;
                    end
            end

            switch velcount
                case 1
                    velorder = vel;
                case 2
                    velorder = [vel vel];
                case 4
                    velorder = [vel vel vel vel];
            end

            velorder = sort(velorder);
            veltemp = []; dirtemp = []; tytemp = [];
            t = 1;
            for s = 1 : length(width)
                veltemp = [veltemp velorder]; %#ok<AGROW>
                dirtemp = [dirtemp dir]; %#ok<AGROW>
                tytemp = [tytemp ty]; %#ok<AGROW>
                stripewidth(t:t+length(velorder)-1) = width(s);
                t = t + length(velorder);
            end

            velorder = veltemp;
            dir = dirtemp;
            ty = tytemp;

            % set order of stimuli, either in a sequence or randomized
            stimorder = 1:size(velorder,2); %#ok<NASGU> % sequence
            for c = 1 : size(dir,2)
                if dir(c) == 1 % CCW FW
                elseif dir(c) == 2 % CWBW
                    velorder(c) = velorder(c)*(-1);
                end
            end

            %%%%%%%%%%%%%%%%%%%%%%%%%% create sequencer to pi %%%%%%%%%%%%%%%%%%%%%%%%%%
            % create sequencer files and put them on pi
            col = str2num(app.stripecolourRGBEditField_3.Value); %#ok<ST2NM>% get colour
           
            % create image
            for g = 1 : length(width)
                im.(['img',num2str(g)]) = create_image(width(g), col, mypi);
            end

            % create toml file (need to adjust frequency and directin of movement)
            for i = 1 : size(ty,2) % not sure whether size 1 or 2
                h = find(stripewidth(i) == width);
                display(stripewidth(i))
                switch type
                    case 'rotational'
                        seq_files(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i));    % arena
                    case 'translational'
                        seq_files_split(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i)) % arena-split
                    case 'both'
                        if ty(i) == 1 % rotation - arena
                            seq_files(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i));    % arena
                        elseif ty(i) == 2 % translation - arena-split
                            seq_files_split(im.(['img',num2str(h)]), velorder(i), duration, 1, mypi, stripewidth(i)) % arena-split
                        end
               end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%% execute stimuli %%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % show stimuli until stop button is pushed
            set(app.StartButton_3, 'UserData', false);
%             realtimefunction(app);
            rec = realtimefunction(app,velorder,ty,stripewidth,mypi);
            
            disp('done')
            if app.SaveCheckBox.Value == 1
                filename = sprintf('%s_Continuous.mat', datestr(now,'yyyymmdd_HHMMSS'));
                save(filename,'rec')
            end

            %%%%%%%%%%%%%%%%%%%%%%%%% clear pi %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            clear mypi
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set(app.StartButton_3,'UserData',true);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StimuliUIFigure = uifigure('Visible', 'off');
            app.StimuliUIFigure.Name = 'Stimuli';
            app.confconf_stripesstripes_num2strveltomlLabel = uilabel(app.StimuliUIFigure);
            app.confconf_stripesstripes_num2strveltomlLabel.Text = {'if not, save files as: '; 'go/bin/conf/conf_stripes/stripes_30.toml'; 'go/bin/conf/conf_stripes/stripes_-30.toml'; 'go/bin/conf/conf_stripes/split_stripes_30.toml'; 'go/bin/conf/conf_stripes/split_stripes_-30.toml'; 'But: -> field for path and filename '; ''; 'FW/BW/CCW/CW defines - or + (movement direction)'};
            app.Label = uilabel(app.StimuliUIFigure);
            app.Label.Text = 'Default: velocities (30 Hz), sampling rate (8 kHz), stripe widths (2), stripe colour (0,255,0)';
            app.TabGroup = uitabgroup(app.StimuliUIFigure);
            app.WidefieldmotionTab = uitab(app.TabGroup);
            app.WidefieldmotionTab.Title = 'Wide-field motion';
            app.testwidefieldmotionPanel = uipanel(app.WidefieldmotionTab);
            app.testwidefieldmotionPanel.Title = 'test wide-field motion';
            app.selectpngfileButton = uibutton(app.testwidefieldmotionPanel, 'push');
            app.selectpngfileButton.ButtonPushedFcn = createCallbackFcn(app, @selectpngfileButtonPushed, true);
            app.selectpngfileButton.Enable = 'off';
            app.selectpngfileButton.Text = 'select png-file';
            app.ButtonGroup = uibuttongroup(app.testwidefieldmotionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.createfilesButton = uiradiobutton(app.ButtonGroup);
            app.createfilesButton.Text = 'create files';
            app.putfilesButton = uiradiobutton(app.ButtonGroup);
            app.putfilesButton.Text = 'put files';
            app.executeButton = uiradiobutton(app.ButtonGroup);
            app.executeButton.Text = 'execute';
            app.executeButton.Value = true;
            app.selecttomlfileButton = uibutton(app.testwidefieldmotionPanel, 'push');
            app.selecttomlfileButton.ButtonPushedFcn = createCallbackFcn(app, @selecttomlfileButtonPushed, true);
            app.selecttomlfileButton.Enable = 'off';
            app.selecttomlfileButton.Text = 'select toml-file';
            app.clockratemhzEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.clockratemhzEditField.Enable = 'off';
            app.clockratemhzEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.clockratemhzEditFieldLabel.Enable = 'off';
            app.clockratemhzEditFieldLabel.Text = 'clock rate (mhz)';
            app.stripecolourRGBEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.stripecolourRGBEditField.Enable = 'off';
            app.stripecolourRGBEditField.Tooltip = {'colour in RGB format. Example: 0,255,0'};
            app.stripecolourRGBEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.stripecolourRGBEditFieldLabel.Enable = 'off';
            app.stripecolourRGBEditFieldLabel.Text = 'stripe colour (RGB)';
            app.stimulusdurationsEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.stimulusdurationsEditFieldLabel.Enable = 'off';
            app.stimulusdurationsEditFieldLabel.Text = 'stimulus duration (s)';
            app.stimulusdurationsEditField = uieditfield(app.testwidefieldmotionPanel, 'numeric');
            app.stimulusdurationsEditField.Enable = 'off';
            app.stimulusdurationsEditField.Value = 5;
            app.FicTracCheckBox = uicheckbox(app.testwidefieldmotionPanel);
            app.FicTracCheckBox.ValueChangedFcn = createCallbackFcn(app, @FicTracCheckBoxValueChanged, true);
            app.FicTracCheckBox.Enable = 'off';
            app.FicTracCheckBox.Text = 'FicTrac';
            app.IPEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.IPEditFieldLabel.Enable = 'off';
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.IPEditField.Enable = 'off';
            app.portEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.portEditFieldLabel.Enable = 'off';
            app.portEditFieldLabel.Text = 'port';
            app.portEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.portEditField.Enable = 'off';
            app.StartButton = uibutton(app.testwidefieldmotionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.directionButtonGroup = uibuttongroup(app.testwidefieldmotionPanel);
            app.directionButtonGroup.Title = 'direction';
            app.CWBWButton = uiradiobutton(app.directionButtonGroup);
            app.CWBWButton.Text = 'CW/ BW';
            app.CCWFWButton = uiradiobutton(app.directionButtonGroup);
            app.CCWFWButton.Text = 'CCW/ FW';
            app.bothButton_2 = uiradiobutton(app.directionButtonGroup);
            app.bothButton_2.Text = 'both';
            app.bothButton_2.Value = true;
            app.orderButtonGroup = uibuttongroup(app.testwidefieldmotionPanel);
            app.orderButtonGroup.Title = 'order';
            app.randomButton = uiradiobutton(app.orderButtonGroup);
            app.randomButton.Text = 'random';
            app.randomButton.Value = true;
            app.sequenceButton = uiradiobutton(app.orderButtonGroup);
            app.sequenceButton.Text = 'sequence';
            app.ButtonGroup_2 = uibuttongroup(app.testwidefieldmotionPanel);
            app.rotationalButton = uiradiobutton(app.ButtonGroup_2);
            app.rotationalButton.Text = 'rotational';
            app.translationalButton = uiradiobutton(app.ButtonGroup_2);
            app.translationalButton.Text = 'translational';
            app.bothButton = uiradiobutton(app.ButtonGroup_2);
            app.bothButton.Text = 'both';
            app.bothButton.Value = true;
            app.movementtypeLabel_2 = uilabel(app.testwidefieldmotionPanel);
            app.movementtypeLabel_2.Text = 'movement type';
            app.pausesEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.pausesEditFieldLabel.Text = 'pause (s)';
            app.pausesEditField = uieditfield(app.testwidefieldmotionPanel, 'numeric');
            app.pausesEditField.Value = 10;
            app.spatfreqrowsEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.spatfreqrowsEditField.Value = '4';
            app.spatfreqrowsEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.spatfreqrowsEditFieldLabel.Text = 'spat. freq. (# rows)';
            app.tempfreqHzEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.tempfreqHzEditField.Value = '0.5, 1, 5, 10, 20, 40, 60, 80, 100, 120';
            app.tempfreqHzEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.tempfreqHzEditFieldLabel.Text = 'temp. freq. (Hz)';
            app.IParenaEditField = uieditfield(app.testwidefieldmotionPanel, 'text');
            app.IParenaEditField.Value = '132.187.28.83';
            app.IParenaEditFieldLabel = uilabel(app.testwidefieldmotionPanel);
            app.IParenaEditFieldLabel.Text = 'IP (arena)';
            app.SaveCheckBox = uicheckbox(app.testwidefieldmotionPanel);
            app.SaveCheckBox.Text = 'Save';
            app.SaveCheckBox.Value = true;
            app.StimulusoutputPanel = uipanel(app.WidefieldmotionTab);
            app.StimulusoutputPanel.Title = 'Stimulus output';
            app.CountPanel = uipanel(app.StimulusoutputPanel);
            app.CountPanel.Title = 'Count';
            app.TotalEditFieldLabel = uilabel(app.CountPanel);
            app.TotalEditFieldLabel.Text = 'Total';
            app.TotalEditField = uieditfield(app.CountPanel, 'numeric');
            app.TotalEditField.Editable = 'off';
            app.CurrentEditFieldLabel = uilabel(app.CountPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.CurrentEditField = uieditfield(app.CountPanel, 'numeric');
            app.CurrentEditField.Editable = 'off';
            app.HistoryPanel = uipanel(app.StimulusoutputPanel);
            app.HistoryPanel.Title = 'History';
            app.EditField = uieditfield(app.HistoryPanel, 'text');
            app.EditField_2 = uieditfield(app.HistoryPanel, 'text');
            app.EditField_3 = uieditfield(app.HistoryPanel, 'text');
            app.movementtypeLabel = uilabel(app.HistoryPanel);
            app.movementtypeLabel.Text = 'movement type';
            app.spatfreqrowsLabel = uilabel(app.HistoryPanel);
            app.spatfreqrowsLabel.Text = 'spat. freq. (# rows)';
            app.tempfreqHzLabel = uilabel(app.HistoryPanel);
            app.tempfreqHzLabel.Text = 'temp. freq. (Hz)';
            app.ContinuousTab = uitab(app.TabGroup);
            app.ContinuousTab.Title = 'Continuous';
            app.testwidefieldmotionPanel_2 = uipanel(app.ContinuousTab);
            app.testwidefieldmotionPanel_2.Title = 'test wide-field motion';
            app.clockratemhzEditField_2 = uieditfield(app.testwidefieldmotionPanel_2, 'text');
            app.clockratemhzEditField_2.Value = '10';
            app.clockratemhzEditField_2Label = uilabel(app.testwidefieldmotionPanel_2);
            app.clockratemhzEditField_2Label.Text = 'clock rate (mhz)';
            app.stripecolourRGBEditField_3 = uieditfield(app.testwidefieldmotionPanel_2, 'text');
            app.stripecolourRGBEditField_3.Tooltip = {'colour in RGB format. Example: 0,255,0'};
            app.stripecolourRGBEditField_3.Value = '0, 255, 0';
            app.stripecolourRGBEditField_3Label = uilabel(app.testwidefieldmotionPanel_2);
            app.stripecolourRGBEditField_3Label.Text = 'stripe colour (RGB)';
            app.stimulusdurationsEditField_2Label = uilabel(app.testwidefieldmotionPanel_2);
            app.stimulusdurationsEditField_2Label.Text = 'stimulus duration (s)';
            app.stimulusdurationsEditField_2 = uieditfield(app.testwidefieldmotionPanel_2, 'numeric');
            app.stimulusdurationsEditField_2.Value = 5;
            app.StopButton = uibutton(app.testwidefieldmotionPanel_2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.pausesEditField_4Label = uilabel(app.testwidefieldmotionPanel_2);
            app.pausesEditField_4Label.Text = 'pause (s)';
            app.pausesEditField_4 = uieditfield(app.testwidefieldmotionPanel_2, 'numeric');
            app.pausesEditField_4.Value = 5;
            app.spatfreqrowsEditField_3 = uieditfield(app.testwidefieldmotionPanel_2, 'text');
            app.spatfreqrowsEditField_3.Value = '8';
            app.spatfreqrowsEditField_3Label = uilabel(app.testwidefieldmotionPanel_2);
            app.spatfreqrowsEditField_3Label.Text = 'spat. freq. (# rows)';
            app.tempfreqHzEditField_3 = uieditfield(app.testwidefieldmotionPanel_2, 'text');
            app.tempfreqHzEditField_3.Value = '40';
            app.tempfreqHzEditField_3Label = uilabel(app.testwidefieldmotionPanel_2);
            app.tempfreqHzEditField_3Label.Text = 'temp. freq. (Hz)';
            app.IParenaEditField_3 = uieditfield(app.testwidefieldmotionPanel_2, 'text');
            app.IParenaEditField_3.Value = '132.187.28.83';
            app.IParenaEditField_3Label = uilabel(app.testwidefieldmotionPanel_2);
            app.IParenaEditField_3Label.Text = 'IP (arena)';
            app.SaveCheckBox_3 = uicheckbox(app.testwidefieldmotionPanel_2);
            app.SaveCheckBox_3.Text = 'Save';
            app.SaveCheckBox_3.Value = true;
            app.StartButton_3 = uibutton(app.testwidefieldmotionPanel_2, 'push');
            app.StartButton_3.ButtonPushedFcn = createCallbackFcn(app, @StartButton_3Pushed, true);
            app.StartButton_3.Text = 'Start';
            app.movementtypeButtonGroup = uibuttongroup(app.testwidefieldmotionPanel_2);
            app.movementtypeButtonGroup.Title = 'movement type';
            app.translationalButton_3 = uiradiobutton(app.movementtypeButtonGroup);
            app.translationalButton_3.Text = 'translational';
            app.translationalButton_3.Value = true;
            app.rotationalButton_3 = uiradiobutton(app.movementtypeButtonGroup);
            app.rotationalButton_3.Text = 'rotational';
            app.bothButton_6 = uiradiobutton(app.movementtypeButtonGroup);
            app.bothButton_6.Text = 'both';
            app.directionButtonGroup_2 = uibuttongroup(app.testwidefieldmotionPanel_2);
            app.directionButtonGroup_2.Title = 'direction';
            app.CWBWButton_2 = uiradiobutton(app.directionButtonGroup_2);
            app.CWBWButton_2.Text = 'CW/ BW';
            app.CCWFWButton_2 = uiradiobutton(app.directionButtonGroup_2);
            app.CCWFWButton_2.Text = 'CCW/ FW';
            app.bothButton_4 = uiradiobutton(app.directionButtonGroup_2);
            app.bothButton_4.Text = 'both';
            app.bothButton_4.Value = true;
            app.StimulusoutputPanel_3 = uipanel(app.ContinuousTab);
            app.StimulusoutputPanel_3.Title = 'Stimulus output';
            app.CountPanel_3 = uipanel(app.StimulusoutputPanel_3);
            app.CountPanel_3.Title = 'Count';
            app.TotalEditField_3Label = uilabel(app.CountPanel_3);
            app.TotalEditField_3Label.Text = 'Total';
            app.TotalEditField_3 = uieditfield(app.CountPanel_3, 'numeric');
            app.TotalEditField_3.Editable = 'off';
            app.CurrentEditField_3Label = uilabel(app.CountPanel_3);
            app.CurrentEditField_3Label.Text = 'Current';
            app.CurrentEditField_3 = uieditfield(app.CountPanel_3, 'numeric');
            app.CurrentEditField_3.Editable = 'off';
            app.HistoryPanel_3 = uipanel(app.StimulusoutputPanel_3);
            app.HistoryPanel_3.Title = 'History';
            app.EditField_9 = uieditfield(app.HistoryPanel_3, 'text');
            app.EditField_10 = uieditfield(app.HistoryPanel_3, 'text');
            app.EditField_11 = uieditfield(app.HistoryPanel_3, 'text');
            app.movementtypeLabel_5 = uilabel(app.HistoryPanel_3);
            app.movementtypeLabel_5.Text = 'movement type';
            app.spatfreqrowsLabel_3 = uilabel(app.HistoryPanel_3);
            app.spatfreqrowsLabel_3.Text = 'spat. freq. (# rows)';
            app.tempfreqHzLabel_3 = uilabel(app.HistoryPanel_3);
            app.tempfreqHzLabel_3.Text = 'temp. freq. (Hz)';
            app.ReceptivefieldsTab = uitab(app.TabGroup);
            app.ReceptivefieldsTab.Title = 'Receptive fields';
            app.testreceptivefieldsPanel = uipanel(app.ReceptivefieldsTab);
            app.testreceptivefieldsPanel.Title = 'test receptive fields';
            app.StartButton_2 = uibutton(app.testreceptivefieldsPanel, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButton_2Pushed, true);
            app.StartButton_2.Text = 'Start';
            app.Panel_5 = uipanel(app.testreceptivefieldsPanel);
            app.singlestripeCheckBox = uicheckbox(app.Panel_5);
            app.singlestripeCheckBox.ValueChangedFcn = createCallbackFcn(app, @singlestripeCheckBoxValueChanged, true);
            app.singlestripeCheckBox.Text = 'single stripe';
            app.ButtonGroup_3 = uibuttongroup(app.Panel_5);
            app.CWButton = uiradiobutton(app.ButtonGroup_3);
            app.CWButton.Enable = 'off';
            app.CWButton.Text = 'CW';
            app.CCWButton = uiradiobutton(app.ButtonGroup_3);
            app.CCWButton.Enable = 'off';
            app.CCWButton.Text = 'CCW';
            app.bothButton_3 = uiradiobutton(app.ButtonGroup_3);
            app.bothButton_3.Enable = 'off';
            app.bothButton_3.Text = 'both';
            app.bothButton_3.Value = true;
            app.ButtonGroup_4 = uibuttongroup(app.Panel_5);
            app.randomButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.randomButton_2.Enable = 'off';
            app.randomButton_2.Text = 'random';
            app.randomButton_2.Value = true;
            app.sequenceButton_2 = uiradiobutton(app.ButtonGroup_4);
            app.sequenceButton_2.Enable = 'off';
            app.sequenceButton_2.Text = 'sequence';
            app.repetitionsEditFieldLabel = uilabel(app.Panel_5);
            app.repetitionsEditFieldLabel.Enable = 'off';
            app.repetitionsEditFieldLabel.Text = 'repetitions';
            app.repetitionsEditField = uieditfield(app.Panel_5, 'numeric');
            app.repetitionsEditField.Enable = 'off';
            app.repetitionsEditField.Value = 5;
            app.tempfreqHzEditField_2Label = uilabel(app.Panel_5);
            app.tempfreqHzEditField_2Label.Enable = 'off';
            app.tempfreqHzEditField_2Label.Text = 'temp. freq. (Hz)';
            app.tempfreqHzEditField_2 = uieditfield(app.Panel_5, 'text');
            app.tempfreqHzEditField_2.Enable = 'off';
            app.tempfreqHzEditField_2.Value = '10, 20, 40, 60, 80';
            app.spatfreqrowsEditField_2Label = uilabel(app.Panel_5);
            app.spatfreqrowsEditField_2Label.Enable = 'off';
            app.spatfreqrowsEditField_2Label.Text = 'spat. freq. (# rows)';
            app.spatfreqrowsEditField_2 = uieditfield(app.Panel_5, 'text');
            app.spatfreqrowsEditField_2.Enable = 'off';
            app.spatfreqrowsEditField_2.Value = '4';
            app.pausesEditField_2Label = uilabel(app.testreceptivefieldsPanel);
            app.pausesEditField_2Label.Enable = 'off';
            app.pausesEditField_2Label.Text = 'pause (s)';
            app.pausesEditField_2 = uieditfield(app.testreceptivefieldsPanel, 'numeric');
            app.pausesEditField_2.Enable = 'off';
            app.pausesEditField_2.Value = 5;
            app.Panel_6 = uipanel(app.testreceptivefieldsPanel);
            app.spiketriggeredaverageCheckBox = uicheckbox(app.Panel_6);
            app.spiketriggeredaverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @spiketriggeredaverageCheckBoxValueChanged, true);
            app.spiketriggeredaverageCheckBox.Text = 'spike triggered average';
            app.imagesEditFieldLabel = uilabel(app.Panel_6);
            app.imagesEditFieldLabel.Enable = 'off';
            app.imagesEditFieldLabel.Text = {'# images'; ''};
            app.imagesEditField = uieditfield(app.Panel_6, 'text');
            app.imagesEditField.Enable = 'off';
            app.imagesEditField.Value = '60';
            app.widthxpxEditFieldLabel = uilabel(app.Panel_6);
            app.widthxpxEditFieldLabel.Enable = 'off';
            app.widthxpxEditFieldLabel.Text = 'width x (px)';
            app.widthxpxEditField = uieditfield(app.Panel_6, 'text');
            app.widthxpxEditField.Enable = 'off';
            app.widthxpxEditField.Value = '16';
            app.widthypxEditFieldLabel = uilabel(app.Panel_6);
            app.widthypxEditFieldLabel.Enable = 'off';
            app.widthypxEditFieldLabel.Text = 'width y (px)';
            app.widthypxEditField = uieditfield(app.Panel_6, 'text');
            app.widthypxEditField.Enable = 'off';
            app.widthypxEditField.Value = '128';
            app.squaresEditFieldLabel = uilabel(app.Panel_6);
            app.squaresEditFieldLabel.Enable = 'off';
            app.squaresEditFieldLabel.Text = '# squares';
            app.squaresEditField = uieditfield(app.Panel_6, 'text');
            app.squaresEditField.Enable = 'off';
            app.squaresEditField.Value = '30';
            app.sizesquarespxLabel = uilabel(app.Panel_6);
            app.sizesquarespxLabel.Enable = 'off';
            app.sizesquarespxLabel.Text = {'size squares '; '(px)'};
            app.sizesquarespxEditField = uieditfield(app.Panel_6, 'text');
            app.sizesquarespxEditField.Enable = 'off';
            app.sizesquarespxEditField.Value = '4';
            app.Button_2 = uibutton(app.Panel_6, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Button_2ValueChanged, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '...';
            app.durationstimsEditFieldLabel = uilabel(app.Panel_6);
            app.durationstimsEditFieldLabel.Enable = 'off';
            app.durationstimsEditFieldLabel.Text = {'duration '; 'stim (s)'};
            app.durationstimsEditField = uieditfield(app.Panel_6, 'text');
            app.durationstimsEditField.Enable = 'off';
            app.durationstimsEditField.Value = '1';
            app.pausesEditField_3Label = uilabel(app.Panel_6);
            app.pausesEditField_3Label.Enable = 'off';
            app.pausesEditField_3Label.Text = 'pause (s)';
            app.pausesEditField_3 = uieditfield(app.Panel_6, 'text');
            app.pausesEditField_3.Enable = 'off';
            app.pausesEditField_3.Value = '1';
            app.EditField_7 = uieditfield(app.Panel_6, 'text');
            app.EditField_7.Enable = 'off';
            app.EditField_7.Value = 'select image folder';
            app.Button = uibutton(app.Panel_6, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Enable = 'off';
            app.Button.Text = '...';
            app.EditField_8 = uieditfield(app.Panel_6, 'text');
            app.EditField_8.Enable = 'off';
            app.EditField_8.Value = 'select toml file';
            app.IParenaEditField_2 = uieditfield(app.testreceptivefieldsPanel, 'text');
            app.IParenaEditField_2.Value = '132.187.28.83';
            app.IParenaEditField_2Label = uilabel(app.testreceptivefieldsPanel);
            app.IParenaEditField_2Label.Text = 'IP (arena)';
            app.stripecolourRGBEditField_2Label = uilabel(app.testreceptivefieldsPanel);
            app.stripecolourRGBEditField_2Label.Text = 'stripe colour (RGB)';
            app.stripecolourRGBEditField_2 = uieditfield(app.testreceptivefieldsPanel, 'text');
            app.stripecolourRGBEditField_2.Value = '0 255 0';
            app.SaveCheckBox_2 = uicheckbox(app.testreceptivefieldsPanel);
            app.SaveCheckBox_2.Text = 'Save';
            app.SaveCheckBox_2.Value = true;
            app.StimulusoutputPanel_2 = uipanel(app.ReceptivefieldsTab);
            app.StimulusoutputPanel_2.Title = 'Stimulus output';
            app.ImageAxes = uiaxes(app.StimulusoutputPanel_2);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes.Visible = 'off';
            app.RedAxes = uiaxes(app.StimulusoutputPanel_2);
            title(app.RedAxes, 'Red')
            xlabel(app.RedAxes, 'Intensity')
            ylabel(app.RedAxes, 'Pixels')
            app.RedAxes.XLim = [0 255];
            app.RedAxes.XTick = [0 128 255];
            app.RedAxes.YTick = [0 1];
            app.RedAxes.Visible = 'off';
            app.GreenAxes = uiaxes(app.StimulusoutputPanel_2);
            title(app.GreenAxes, 'Green')
            xlabel(app.GreenAxes, 'Intensity')
            ylabel(app.GreenAxes, '')
            app.GreenAxes.XLim = [0 255];
            app.GreenAxes.XTick = [0 128 255];
            app.GreenAxes.YTick = [0 1];
            app.GreenAxes.Visible = 'off';
            app.BlueAxes = uiaxes(app.StimulusoutputPanel_2);
            title(app.BlueAxes, 'Blue')
            xlabel(app.BlueAxes, 'Intensity')
            ylabel(app.BlueAxes, '')
            app.BlueAxes.XLim = [0 255];
            app.BlueAxes.XTick = [0 128 255];
            app.BlueAxes.YTick = [0 1];
            app.BlueAxes.Visible = 'off';
            app.CountPanel_2 = uipanel(app.StimulusoutputPanel_2);
            app.CountPanel_2.Title = 'Count';
            app.CountPanel_2.Visible = 'off';
            app.TotalEditField_2Label = uilabel(app.CountPanel_2);
            app.TotalEditField_2Label.Text = 'Total';
            app.TotalEditField_2 = uieditfield(app.CountPanel_2, 'numeric');
            app.TotalEditField_2.Editable = 'off';
            app.CurrentEditField_2Label = uilabel(app.CountPanel_2);
            app.CurrentEditField_2Label.Text = 'Current';
            app.CurrentEditField_2 = uieditfield(app.CountPanel_2, 'numeric');
            app.CurrentEditField_2.Editable = 'off';
            app.HistoryPanel_2 = uipanel(app.StimulusoutputPanel_2);
            app.HistoryPanel_2.Title = 'History';
            app.HistoryPanel_2.Visible = 'off';
            app.EditField_4 = uieditfield(app.HistoryPanel_2, 'text');
            app.EditField_5 = uieditfield(app.HistoryPanel_2, 'text');
            app.EditField_6 = uieditfield(app.HistoryPanel_2, 'text');
            app.movementtypeLabel_3 = uilabel(app.HistoryPanel_2);
            app.movementtypeLabel_3.Text = 'movement type';
            app.spatfreqrowsLabel_2 = uilabel(app.HistoryPanel_2);
            app.spatfreqrowsLabel_2.Text = 'spat. freq. (# rows)';
            app.tempfreqHzLabel_2 = uilabel(app.HistoryPanel_2);
            app.tempfreqHzLabel_2.Text = 'temp. freq. (Hz)';
            app.POLTab = uitab(app.TabGroup);
            app.POLTab.Title = 'POL';
            app.Panel = uipanel(app.POLTab);
            app.commandEditField_2Label = uilabel(app.Panel);
            app.commandEditField_2Label.Text = 'command';
            app.commandEditField_2 = uieditfield(app.Panel, 'text');
            app.GoButton = uibutton(app.Panel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            % Show the figure after all components are created
            app.StimuliUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Arena_20230123_05

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StimuliUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StimuliUIFigure)
        end
    end
end